classdef Image_w0rk_3_3_3_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        Tab                     matlab.ui.container.Tab
        ImageAxes               matlab.ui.control.UIAxes
        ImageAxes_2             matlab.ui.control.UIAxes
        LoadButton              matlab.ui.control.Button
        ConvertingImageButton   matlab.ui.control.Button
        ScalingImageButton      matlab.ui.control.Button
        Label                   matlab.ui.control.Label
        SliderLabel             matlab.ui.control.Label
        Slider                  matlab.ui.control.Slider
        StartButton             matlab.ui.control.Button
        Tab_2                   matlab.ui.container.Tab
        UITable                 matlab.ui.control.Table
        insertdataButton        matlab.ui.control.Button
        ImageAxes_3             matlab.ui.control.UIAxes
        RotateButton            matlab.ui.control.Button
        Label3                  matlab.ui.control.Label
        Label4                  matlab.ui.control.Label
        Label5                  matlab.ui.control.Label
        Label6                  matlab.ui.control.Label
        FilenameEditFieldLabel  matlab.ui.control.Label
        FilenameEditField       matlab.ui.control.EditField
        AreaExcelButton         matlab.ui.control.Button
        Tab_3                   matlab.ui.container.Tab
        UITable2                matlab.ui.control.Table
        insertdata_1Button      matlab.ui.control.Button
        UITable3                matlab.ui.control.Table
        Label_2                 matlab.ui.control.Label
        Label_3                 matlab.ui.control.Label
        insertdata_2Button      matlab.ui.control.Button
        Label7                  matlab.ui.control.Label
        Label8                  matlab.ui.control.Label
        pocketsearchButton      matlab.ui.control.Button
        UITable8                matlab.ui.control.Table
        Label_7                 matlab.ui.control.Label
        Tab_4                   matlab.ui.container.Tab
        Insertdata_3Button      matlab.ui.control.Button
        UITable5                matlab.ui.control.Table
        UITable6                matlab.ui.control.Table
        Label_4                 matlab.ui.control.Label
        Label_5                 matlab.ui.control.Label
        Tab_5                   matlab.ui.container.Tab
        UITable7                matlab.ui.control.Table
        AdddataButton           matlab.ui.control.Button
        Label_6                 matlab.ui.control.Label
        NameEditFieldLabel      matlab.ui.control.Label
        NameEditField           matlab.ui.control.EditField
        WriteExcelButton        matlab.ui.control.Button
        Tab_6                   matlab.ui.container.Tab
        PointsdisplayButton     matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property15 % Description
        stats1        % Description
        Property33 % Description
    end
    
    properties (Access = public)
        IM % Description
        I % Description
        invImg % Description
        stats % Description
        L_Num % Description
        background % Description
        L % Description
        grain % Description
        arrangle % Description
        Angle_ok % Description
        grain_fin % Description
        arrpoint % Description
        points_left % Description
        arrpoint_L % Description
        S_g % Description
        S_H % Description
        Num_points % Description
        Property19 % Description
        Old_Data % Description
         Data_Points % Description
        value % Description
        Data_Area % Description
        Old_Data_Area % Description
        maybe_pocket % Description
         no_pocket % Description
       arrpocket % Description
        arrsize_pocket2 % Description
        arrstart_pocket1 % Description
        arrend_pocket1 % Description
        schet_ind % Description
            end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
             % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
                       
            if (ischar(p))
                fname = [p f];
                app.IM = imread(fname);
%  Масштабируем _уменьшаем в 2 раза, чтобы ускорить работу с изображением                
               app.IM = imresize(app.IM, 0.5);
                imagesc(app.ImageAxes,app.IM);
            end 
        end

        % Button pushed function: ConvertingImageButton
        function Converting_image(app, event)
            I1 = imadjust(app.IM,[.18 .66],[.05 1 ],[]);
%             imagesc(app.ImageAxes_2,I1);
            s = rgb2gray(I1);
%             imagesc(app.ImageAxes_2,s);
              I3 = imadjust(s);
               bw = imbinarize(I3, app.Slider.Value/100.0);
               bw = ~bw;
%               imshow(bw);

%           Заполнение всех отверстий, чтобы можно было использовать regionprops
% для оценки площади, ограниченной каждой из границ.   
            se = strel('disk',2);
            bw = imclose(bw,se);
%           imshow(bw_1);
          
          %    Удаление всех объектов размером меньше 30 пикселей(шум)            
          app.invImg = bwareaopen(bw,30);
          
%           bw = bw_1 < 255;
            app.invImg = imfill(app.invImg, 'holes');
            imagesc(app.ImageAxes_2,app.invImg);
            
        end

        % Button pushed function: ScalingImageButton
        function Image_splittingButton(app, event)
%       Выделение отдельных объектов на изображении
            
            se = strel('disk',8);
            app.background = imopen(app.invImg,se);
            app.L = bwconncomp(app.background,4);
            Num = num2str(app.L.NumObjects);
            app.L_Num = app.L.NumObjects;
            app.L.ImageSize;
            app.stats = regionprops(app.L, 'all');
            app.Label.Text = ['Распознано',    Num ,' объектов'];

%          
        end

        % Size changed function: Tab_2
        function Update_object2(app, event)
            position = app.Tab_2.Position;
%             
        end

        % Button pushed function: insertdataButton
        function insert_data(app, event)
          
            imagesc(app.ImageAxes_3,app.invImg);
% % Добавление на изображение границ и сеток объектов          

%             i= app.L_Num;
            imshow(app.invImg);
            blobAnalysis = vision.BlobAnalysis('BoundingBoxOutputPort', true, ...
            'AreaOutputPort', false, 'CentroidOutputPort',  false, ...
            'MinimumBlobArea', 100);
         blobAnalysis1 = vision.BlobAnalysis('BoundingBoxOutputPort', false, ...
            'AreaOutputPort', false, 'CentroidOutputPort',  true, ...
            'MinimumBlobArea', 100);
            bbox = step(blobAnalysis, app.background);
             bbox1 = step(blobAnalysis1, app.background); 
            result = insertShape(app.IM, 'Rectangle', bbox, 'Color', 'green','LineWidth',6);
            numCars = size(bbox, 1);
            result = insertText(result, [10 10], numCars, 'BoxOpacity', 1, ...
            'FontSize', 14);
            imshow(result); title('Detected');
              for k = 1:app.L_Num
                   Yonepoint1= app.stats(k).BoundingBox(1);
                   Xonepoint1= app.stats(k).BoundingBox(2);
                   text( Yonepoint1,Xonepoint1, num2str(k),'Color','y',...
                   'FontSize',14,'FontWeight','bold');
                text( Yonepoint1+20,Xonepoint1+20, num2str(app.stats(k).Centroid(1)),'Color','y',...
                   'FontSize',14,'FontWeight','bold');
               text( Yonepoint1+20,Xonepoint1+40, num2str(app.stats(k).Centroid(2)),'Color','y',...
                   'FontSize',14,'FontWeight','bold');
              end 
% Разметка талицы под данные из структурного файла изоражения    
            app.UITable.ColumnName = {'Number','Centroid_x', 'Centroid_y', 'Ar','Orientation','MinorAx','MajorAx'};
            app.UITable.ColumnWidth = {'auto', 'auto', 'auto','auto','auto','auto','auto'};    
%     Конвертация данных из структурного файла обработанного изоражения в талицу appdesigner      
            i= app.L_Num;
           t=[];
            for q=1:i
                x = [q app.stats(q).Centroid(1) app.stats(q).Centroid(2) app.stats(q).Area app.stats(q).Orientation app.stats(q).MinorAxisLength app.stats(q).MajorAxisLength];
                t = [x; t];
            end   
             app.UITable.Data = t;
             app.Data_Area=t;
             app.Old_Data_Area= [app.Data_Area;  app.Old_Data_Area];
        end

        % Callback function
        function AxiscreationButtonPushed(app, event)
            app.grain = false(size(app.background));
            app.grain = double(app.grain);
%    копия для окончательного доворачивания
            app.grain_fin = app.grain;
            for q=1:app.L_Num
%                 app.grain = false(size(app.background));
% %                
                 app.grain(app.L.PixelIdxList{q}) = true;
%                 отображение распознаваемого листа до поворотов
%                 imshow(app.grain);
            end    
%   
%  
        end

        % Button pushed function: RotateButton
        function Rotate(app, event)
%        Массив точек всех объектов     
          app.arrpoint=[];
            % пересчет угла доворота от центра листа по углу главной оси эллипса
           
             app.grain = false(size(app.background));
             app.grain = double(app.grain);
%          счетчик объектов    
                for q=1:app.L_Num 
                     app.grain(app.L.PixelIdxList{q}) = true;
                     
 %                Расчет расположения осевой линии, проходящей через центроиду по главной оси эллипса                        
                     Image_rotate = imrotate(app.grain, 90-app.stats(q).Orientation, 'loose', 'bilinear');
                             
% %              figure, imshow(Image_rotate),title('Повернута ось центроиды');
                     app.grain(app.L.PixelIdxList{q}) = false;
                     save('Image_rotate','Image_rotate');
%                      Работа с повернутым отдельным объектом,расчет угла доворота
                     se1 = strel('disk',8);
                     list = imopen(Image_rotate,se1);
                     L1 = bwconncomp(list,8);
                     L1.NumObjects;


                     app.stats1 = regionprops(L1, 'all');     


                     list_PixelList1 = [app.stats1(1).PixelList];
                     [min_PixelList1, idx] = min(list_PixelList1);

                
                     Yonepoint1 = app.stats1.Centroid(2)-app.stats1.MajorAxisLength/2;
                     Ytwopoint1 =app.stats1.MajorAxisLength/2+ app.stats1.Centroid(2);
                     Xonepoint1 = app.stats1.Centroid(1);
                     Xtwopoint1 = app.stats1.Centroid(1);

        
       General_line1 = insertShape(Image_rotate,'Line',[Xonepoint1, Yonepoint1,  Xtwopoint1, Ytwopoint1],...
                'Color', 'green','LineWidth',3);
          
%              General_Point1 = insertShape(Image_rotate,'point',[Xonepoint1, Yonepoint1],...
%                 'Color', 'green','LineWidth',3);
                     figure, imshow(General_line1),title('Повернута ось центроиды приблизительно вертикально');  
%            Доворот объекта длинной осью до вертикали только для био
% % %                     Image_rotate = imrotate(Image_rotate,   -app.Angle_ok, 'loose', 'bilinear');
% %                     figure, imshow(Image_rotate),title('Повернута ось вертикально окончательно');  
%   Масштабирование StandArea- площадь эталона
                StandArea =6000;
%                 Коэффициент накопленной ошибки от преобразований из структуры в картинку, который влияет на фактическое масштаирование
%                 определяется в ходе подбора сравнением фактическогой площади после масштабирования
%                 w=0.78;

%%                app.stats(q).Scale= StandArea/app.stats(q).Area;
% Для деталей масштаб =1 для био расчитывается: Image_scaling = imresize(Image_rotate,  app.stats(q).Scale);
                 app.stats(q).Scale=1;
% %                 Image_scaling = imresize(Image_rotate,  app.stats(q).Scale);
%                    figure, imshow(Image_scaling),title('Изображение в масштабе эталона');
%                        Переприсвоение, чтобы не напутать
%                 Image_rotate= Image_scaling;
%      Расчет точек внешнего контура  
% Опять  получение мин и максимальных значений
                     se1 = strel('disk',8);
                     list = imopen(Image_rotate,se1);
                     L1 = bwconncomp(list,8);
                     L1.NumObjects;
                     
                     stats1 = regionprops(L1, 'PixelList');
                     list_PixelList1 = [stats1(1).PixelList];
                     [min_PixelList1, idx] = min(list_PixelList1);
                     [max_PixelList1, idx1] = max(list_PixelList1);
                     Yonepoint1 = min_PixelList1(2);
                     Ytwopoint1 = max_PixelList1(2);
                     Xonepoint1 = list_PixelList1(idx(2),1);
                     Xtwopoint1 = list_PixelList1(idx1(2),1);
%     для подсчета суммы единичек в определении есть ли карман                  
                     
                     Xonepoint =  min_PixelList1(1);
                     Xtwopoint = max_PixelList1(1);


% получение массива хорд для правой стороны
 
f = gather(Image_rotate);
%  figure, imshow(f);
%    
%    G = f(k,:); 
%  k = Yonepoint1;
%   k1 = num2str(k);
%  app.Label3.Text = ['Точка отсчета',    k1       ,' пикселей']; 
 Num = num2str(Ytwopoint1);
  app.Label4.Text = ['Конец отсчета',    Num ,' пикселей'];
%    Num = abs(Num);
% %  Число точек app.Num_points обязательно должно делиться на 2  и не большим, пределяется опытным путем 
   app.Num_points=22;  
   
   Num = floor((Ytwopoint1-Yonepoint1)/app.Num_points);
    Num1 = (Ytwopoint1-Yonepoint1)/app.Num_points;
    accum_error= (Num1-Num)*app.Num_points;
%    Num1 = num2str(Num);
%   app.Label5.Text = ['шаг',    Num1 ,' пикселей'];

% % подсчет реального числа пикселей листовой пластинки 
%r-шаг по оси У, за один шаг в каждый массив попадает по одному элементу:
%точки краев,точки кармана, привязанного к точке края, карман считается
%если нет совпадения суммы единичек пластинки и разницы между двумя краями
r=0;
 for k = Yonepoint1+Num : Num : (Ytwopoint1-accum_error)
    sel=0;
  for m =  Xonepoint : Xtwopoint
       G= f(k,m);
      if  G==1
        sel=sel+1;
%              i=i+1;  
   %  Складываем все 1 в одной строке 
      end
  end 
  r=r+1;
  app.maybe_pocket(q,r)=sel;
%  maybe_pocket(q,r) счтиает для одной координаты Y все ненулевые точки и если сравнить их с разницей можно выяснить есть ли внутри карманы         
% % % ? end                
  
% %   Счетчик точек
  % % % ?  r=0;
% % % ? for k = Yonepoint1+Num : Num : Ytwopoint1
% Определение числа столбцов матрицы G -- numOfCols

   G = f(k,:); 
%    sel= 0; 
 numOfCols = size( G, 2);
       left1=[];

       %  Складываем все 0 в одной строке слева от оси  app.arrpoint_L массив левых точек 
            i=1; sel=0;
          while G(i)==0 
             sel=sel+1;
             i=i+1;
%           
              if G(i)==1               
         break;
              end 
%           
          end 
    % % % ?       r=r+1;
               arrnol_L(q,r)=sel;
             app.arrpoint_L(q,r)=Xonepoint1-arrnol_L(q,r); 
 % % % ?end
 % % % ?r=0;
 % % % ?for k = Yonepoint1+Num : Num : Ytwopoint1
  % % % ? G = f(k,:);
%    зачем здесьYonepoint1+Num?
   %  Складываем все 0 в одной строке справа от оси,  - app.arrpoint массив правых точек
       i=numOfCols; 
       i4=length(G);
       i1=0;
       sel=0;
          while G(i)==0 
             sel=sel+1;
             i=i-1;
           i1=i; 
            if G(i)~=0  
%                 i1=i;  
         break;
              end 
           
          end 
          % % % ? r=r+1;
           arrnol_R(q,r)=sel;
           app.schet_ind=sel;
           app.arrpoint(q,r)=length(G)-Xonepoint1-arrnol_R(q,r);
       
%             app.no_pocket(q,r)= app.arrpoint(q,r)+app.arrpoint_L(q,r);
           app.no_pocket(q,r)= app.arrpoint(q,r)+app.arrpoint_L(q,r);
           app.arrpocket(q,r)= app.no_pocket(q,r)-app.maybe_pocket(q,r);
% %  end 
% %    r=0;
% %  for k = Yonepoint1+Num : Num : Ytwopoint1
% %    G = f(k,:);
%    зачем здесьYonepoint1+Num?
   %  Складываем все 0 в одной строке справа от оси,  - app.arrpoint массив правых точек
% %        i=length(G);
%           sel=0;
% считаем только круаные карманы
       arrstart=sel;
          i1=numOfCols-sel;
          while  G(i1-1)>0 
             sel=sel+1;
             i1=i1-1;
         
         
             i2=i1;
             if G(i1)==0 
                i2=i1;  
         break;
              end 
          end 
             arrstart=sel;
          if  app.arrpocket(q,r)<=4              
               app.schet_ind=0;
              arrstart=0;
           
                    end 
%             app.arrstart_pocket1(q,r)=arrstart; 
            app.arrstart_pocket1(q,r)=arrstart-app.schet_ind;
%             app.arrstart_pocket1(q,r)=arrnol_R(q,r);
% расстояемк от края до кромки
     
          i2=numOfCols-sel;
               while   G(i2-1)==0 
             sel=sel+1;
             i2=i2-1;
              arrend=sel;
%                          
                end 
    if  app.arrpocket(q,r)<=4
                arrend=0;
    end
    
         app.arrend_pocket1(q,r)=arrend-arrstart;  

   
 end     

            end
     
          % Сумма arrnol_L(q,r) площадь фона объекта, который нужно вычесть из Xonepoint1,
%           чтобы получить полухорду и координату точки относительно вертикальной оси  
%           если фигура вогнутая в ось симметрии -получаем отрицательную координату  
% %  no_pocket(q,r) -  разница между правой и левой кромкой, посчитанная для точек соответствующих одной координате  У
%  (одной строке, заданной через определенный шаг без учета возможных карманов)
% там может быть карман, а может и не быть
% % %    app.no_pocket(q,r)= app.arrpoint(q,r)+app.arrpoint_L(q,r);
%   arrpocket(q,r) -массив содержит возможные карманы, привязанные к одной
%   координате у, нужен для проверки наличия кармана, чтобы его привязать к
%   точке края и определить размер кармана, а они могут быть с двухсторон
% % %    app.arrpocket(q,r)=app.maybe_pocket(q,r)- app.no_pocket(q,r);
% % Массивы под карманы для вырезов 
%    
%            i1=app.Num_points;
%             t1=[];
%                 for r=1:i1   
%                   if   app.arrpocket(q,r)>3
% %     Взять точку слева от края и от этой точки искать начало кармана  
%  arrnol_L(q,r)
% %                  end


% %             Проверка   
%           Num = num2str(app.arrpoint(1,2));
%     app.Label6.Text = ['Длина полухорды',    Num ,' пикселей'];
        end

        % Button pushed function: insertdata_1Button
        function insert_data3(app, event)
%   Отладочная Эту функцию можно потом закоментировать она нужна,чтобы видеть перестановку четвертинок        
            % Разметка талицы под данные из структурного файла изоражения после всех изменений оси  
       
            app.UITable2.ColumnName = {'Number obj', '1','2','3','4','5','6','7','8','9','10'};
            app.UITable2.ColumnWidth = {'auto', 'auto', 'auto','auto','auto','auto', 'auto', 'auto','auto','auto','auto'};    
%     Конвертация данных из структурного файла обработанного изоражения в талицу appdesigner      
            i= app.L_Num
            t=[]; 
            for q=1:i

% %          app.stats(q).Scale  - объемный масштабный коэффициент объекта q
              Scale = app.stats(q).Scale;
              G1= app.arrpoint(q,:);
%                 Подсчет пропорции  площади  
% app.S_g(q) сумма всех точек *на масштаб -масштабированная площадь правой стороны q ого листа

               app.S_g(q)=sum(floor(G1*Scale));
               S_g=num2str(app.S_g);
               app.Label7.Text = ['S_g',   S_g  ];  
                 x=[q floor(G1*Scale)];
                t = [x; t];
            end
% % Подсчет пропорции  площади 
%                 G=[floor(G1*Scale)];
%                  G=G(:,2);
%                 app.S_g=sum(G);
%                  app.stats(q).S_g=app.S_g;

           
%         Добавление данных по точкам в таблицу
             app.UITable2.Data = t;  
        
        end

        % Button pushed function: insertdata_2Button
        function insertdata_2ButtonPushed(app, event)
 %   Отладочная Эту функцию можно потом закоментировать она нужна,чтобы видеть перестановку четвертинок         
            % Разметка талицы под данные из структурного файла изоражения после всех изменений оси  
       
            app.UITable3.ColumnName = {'Number obj', '1','2','3','4','5','6','7','8','9','10'};
            app.UITable3.ColumnWidth = {'auto', 'auto', 'auto','auto','auto','auto', 'auto', 'auto','auto','auto','auto'};    
%     Конвертация данных из структурного файла обработанного изоражения в талицу appdesigner      
            i= app.L_Num
            t=[]; 
            for q=1:i

% %          app.stats(q).Scale  - объемный масштабный коэффициент объекта q
              Scale = app.stats(q).Scale;
                G1= app.arrpoint_L(q,:);
%                Подсчет пропорции  площади 
% app.S_H(q) сумма всех точек *на масштаб -масштабированная площадь левой стороны q ого листа
               app.S_H(q)=sum(floor(G1*Scale));
               S_H=num2str(app.S_H);
               app.Label8.Text = ['S_H',   S_H ]; 
               
                x=[q floor(G1*Scale)];
                t = [x; t];
            end 
         % % Подсчет пропорции правой площади

  
%         Добаавление данных по точкам в таблицу
             app.UITable3.Data = t; 
        
        end

        % Button pushed function: Insertdata_3Button
        function Insertdata_3ButtonPushed(app, event)
        i= app.L_Num
            t=[]; t1=[]; 
            for q=1:i
                 Scale = app.stats(q).Scale;
                 G1= app.arrpoint(q,:);
%                 Подсчет пропорции  площади  
                 app.S_g(q)=sum(floor(G1*Scale));
               S_g=num2str(app.S_g);
                G2= app.arrpoint_L(q,:);
%                Подсчет пропорции  площади  
               app.S_H(q)=sum(floor(G2*Scale));
%    отношение правой части листа к левой            
            relation_S(q)= app.S_g(q)/app.S_H(q);
            S_H=num2str(app.S_H(q));
              S_g=num2str(app.S_g(q));
            app.Label_4.Text = ['S_g', S_g  ];
             app.Label_5.Text = ['S_H',S_H ];
%            В верхнюю таблицу попадают точки большей стороны листа в нижнюю - меньшей
 
   if abs(relation_S(q))>1
%   У большей половины Первая по порядку половина точек в сумме должна быть больше чем вторая половина 
% разделение на четвертинки

       n=app.Num_points/2;
       B=reshape(floor(G1*Scale),n,2);
%        B=reshape(floor(G1*Scale),[app.Num_points/2,2]);
% суммирование точек четвертинок  quarter_sum1 -верхняя
      quarter_sum1=sum(B(:,1));
      quarter_sum2=sum(B(:,2));
      relation_quarters=quarter_sum1/quarter_sum2;
      diap=app.Num_points:-1:1;
     if  abs(relation_quarters)>1
        x=[floor(G1*Scale)];
        t = [x; t];
        x1=[floor(G2*Scale)];
        t1 = [x1; t1]; 
     end
     if abs(relation_quarters)<1
       
        G1=G1(:,[diap]); 
        x=[floor(G1*Scale)];
        t = [x; t];
         G2=G2(:,[diap]); 
        x1=[floor(G2*Scale)];
        t1 = [x1; t1]; 
     end
   end  
   if abs(relation_S(q))<1
%        B=reshape(floor(G2*Scale),[app.Num_points/2,2]);

 n=app.Num_points/2;
 B=reshape(floor(G2*Scale),n,2);
% суммирование точек четвертинок  quarter_sum1 -верхняя
      quarter_sum1=sum(B(:,1));
      quarter_sum2=sum(B(:,2));
      relation_quarters=quarter_sum1/quarter_sum2;
      diap=app.Num_points:-1:1;
       if  abs(relation_quarters)>1
        x=[floor(G2*Scale)];
        t = [x; t];
        x1=[floor(G1*Scale)];
        t1 = [x1; t1];
       end
        if abs(relation_quarters)<1
       
        G2=G2(:,[diap]); 
        x=[floor(G2*Scale)];
        t = [x; t];
         G1=G1(:,[diap]); 
        x1=[floor(G1*Scale)];
        t1 = [x1; t1]; 
        end
   end
end  
        app.UITable5.Data = t;
        app.UITable6.Data =  t1;
        
        app.Data_Points=[t, t1];
%    Отображение результата объединения текущих данных с предыдущими
        app.UITable7.Data = [app.Data_Points;  app.Old_Data];
       
%         xlswrite('t',Data_Points);
%         p=xlsread('t.xls');
        end

        % Button pushed function: AdddataButton
        function AdddataButtonPushed(app, event)
          app.Label_6.Text='Данные предыдущего шага добавлены к описанию объекта'; 
           app.Old_Data= [app.Data_Points;  app.Old_Data];
           
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
% Чтобы заполнить 100 обучающих примеров, необходимо обработать  10 и более фото, каждое  фото дает
% параметры точек в app.Data_Points и они должны добавиться к остальным точкам app.Old_Data 
             app.Old_Data= [];
             app.Old_Data_Area= [];
        end

        % Value changed function: NameEditField
        function NameEditFieldValueChanged(app, event)
%     Имена классов объектов, которые должна распознавать нейросеть вводит пользователь
% с одним именем связано 100 обучающих примеров имя файла= имя класса объектов
            app.value = app.NameEditField.Value;
           
        end

        % Button pushed function: WriteExcelButton
        function WriteExcelButtonPushed(app, event)
%    Точки всех 100 объектов обучения записываются в файл эксель, имя которого латинское имя объекта        
            xlswrite(app.value, app.Old_Data); 
        end

        % Callback function
        function timeButtonPushed(app, event)
           
        end

        % Callback function
        function WriteExcelButton_2Pushed(app, event)
%   Запись в файл площадей, углов и осей объектов          
            xlswrite(app.value, app.Old_Data);  
        end

        % Value changed function: FilenameEditField
        function FilenameEditFieldValueChanged(app, event)
            app.value = app.FilenameEditField.Value;
            
        end

        % Button pushed function: AreaExcelButton
        function AreaExcelButtonPushed2(app, event)
%            Old_Data_Area - контейнер всех объектов одного класса, когда
%            все фото объектов одного класса будут распознаны - записываем
%            их параметры в excel
            xlswrite(app.value, app.Old_Data_Area);  
        end

        % Button pushed function: pocketsearchButton
        function pocketsearch(app, event)
%  Вывод в таблицу координаты начала кармана         
            i= app.L_Num; 
             t=[];
          for q=1:i
%                  
% %          app.stats(q).Scale  - объемный масштабный коэффициент объекта q
              Scale = app.stats(q).Scale;
%               G1= app.maybe_pocket(q,:);
% Размеры обоих карманов в сумме
%                   G1=app.arrpocket(q,:);
                 G1= app.arrstart_pocket1(q,:);
%                  G1= app.arrend_pocket1(q,:);
              
%               G1=app.arrsize_pocket2(q,:);
%                 Подсчет пропорции  площади  
% app.S_g(q) сумма всех точек *на масштаб -масштабированная площадь правой стороны q ого листа

               app.S_g(q)=sum(floor(G1*Scale));
               x=[q floor(G1*Scale)];
             
                t = [x; t];
            end
           
%         Добавление данных по точкам в таблицу
             app.UITable8.Data = t;    
        end

        % Button pushed function: PointsdisplayButton
        function PointsdisplayButtonPushed(app, event)
%        Отображение кромки в виде графика
 i= app.L_Num
  for q=1:i
%   x1 =  app.arrpoint(q,:);
   x =   app.Data_Points(q,:);
   m=length(x)/2;
 x1= x(1:m);
 x2=x(m+1:end)

   y1 =  1:  length(x)/2; 
%   y1 =  1: app.Num_points;
  figure(q)
%     plot(x1,y1,'o-');
plot(x1,y1,'o-');
hold on;
plot(-x2,y1,'o-');
hold off;
%     plot([x1 -x2],y1,'o-');
  end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Этап 1';
            app.ImageAxes = uiaxes(app.Tab);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.ImageAxes_2 = uiaxes(app.Tab);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = '';
            app.ImageAxes_2.YTick = [];
            app.LoadButton = uibutton(app.Tab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.ConvertingImageButton = uibutton(app.Tab, 'push');
            app.ConvertingImageButton.ButtonPushedFcn = createCallbackFcn(app, @Converting_image, true);
            app.ConvertingImageButton.Text = {'Converting Image'; ''};
            app.ScalingImageButton = uibutton(app.Tab, 'push');
            app.ScalingImageButton.ButtonPushedFcn = createCallbackFcn(app, @Image_splittingButton, true);
            app.ScalingImageButton.Text = 'Scaling Image';
            app.Label = uilabel(app.Tab);
            app.SliderLabel = uilabel(app.Tab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.Tab);
            app.Slider.Value = 50;
            app.StartButton = uibutton(app.Tab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.SizeChangedFcn = createCallbackFcn(app, @Update_object2, true);
            app.Tab_2.Title = 'Этап 2';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.insertdataButton = uibutton(app.Tab_2, 'push');
            app.insertdataButton.ButtonPushedFcn = createCallbackFcn(app, @insert_data, true);
            app.insertdataButton.Text = 'insert data';
            app.ImageAxes_3 = uiaxes(app.Tab_2);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.XTickLabel = '';
            app.ImageAxes_3.YTick = [];
            app.RotateButton = uibutton(app.Tab_2, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @Rotate, true);
            app.RotateButton.Text = 'Rotate';
            app.Label3 = uilabel(app.Tab_2);
            app.Label3.Text = 'Label3';
            app.Label4 = uilabel(app.Tab_2);
            app.Label4.Text = 'Label4';
            app.Label5 = uilabel(app.Tab_2);
            app.Label5.Text = 'Label5';
            app.Label6 = uilabel(app.Tab_2);
            app.Label6.Text = 'Label6';
            app.FilenameEditFieldLabel = uilabel(app.Tab_2);
            app.FilenameEditFieldLabel.Text = 'File name';
            app.FilenameEditField = uieditfield(app.Tab_2, 'text');
            app.FilenameEditField.ValueChangedFcn = createCallbackFcn(app, @FilenameEditFieldValueChanged, true);
            app.AreaExcelButton = uibutton(app.Tab_2, 'push');
            app.AreaExcelButton.ButtonPushedFcn = createCallbackFcn(app, @AreaExcelButtonPushed2, true);
            app.AreaExcelButton.Text = ' Area Excel';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Этап 3';
            app.UITable2 = uitable(app.Tab_3);
            app.UITable2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable2.RowName = {};
            app.insertdata_1Button = uibutton(app.Tab_3, 'push');
            app.insertdata_1Button.ButtonPushedFcn = createCallbackFcn(app, @insert_data3, true);
            app.insertdata_1Button.Text = 'insert data_1';
            app.UITable3 = uitable(app.Tab_3);
            app.UITable3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable3.RowName = {};
            app.Label_2 = uilabel(app.Tab_3);
            app.Label_2.Text = 'Правая сторона';
            app.Label_3 = uilabel(app.Tab_3);
            app.Label_3.Text = 'Левая сторона';
            app.insertdata_2Button = uibutton(app.Tab_3, 'push');
            app.insertdata_2Button.ButtonPushedFcn = createCallbackFcn(app, @insertdata_2ButtonPushed, true);
            app.insertdata_2Button.Text = 'insert data_2';
            app.Label7 = uilabel(app.Tab_3);
            app.Label7.Text = 'Label7';
            app.Label8 = uilabel(app.Tab_3);
            app.Label8.Text = 'Label8';
            app.pocketsearchButton = uibutton(app.Tab_3, 'push');
            app.pocketsearchButton.ButtonPushedFcn = createCallbackFcn(app, @pocketsearch, true);
            app.pocketsearchButton.Text = 'pocket search';
            app.UITable8 = uitable(app.Tab_3);
            app.UITable8.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable8.RowName = {};
            app.Label_7 = uilabel(app.Tab_3);
            app.Label_7.Text = 'Проверка есть ли карманы';
            app.Tab_4 = uitab(app.TabGroup);
            app.Tab_4.Title = 'Этап 4';
            app.Insertdata_3Button = uibutton(app.Tab_4, 'push');
            app.Insertdata_3Button.ButtonPushedFcn = createCallbackFcn(app, @Insertdata_3ButtonPushed, true);
            app.Insertdata_3Button.Text = 'Insert data_3';
            app.UITable5 = uitable(app.Tab_4);
            app.UITable5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable5.RowName = {};
            app.UITable6 = uitable(app.Tab_4);
            app.UITable6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable6.RowName = {};
            app.Label_4 = uilabel(app.Tab_4);
            app.Label_4.Text = 'Большая сторона справа';
            app.Label_5 = uilabel(app.Tab_4);
            app.Label_5.Text = 'Меньшая сторона слева';
            app.Tab_5 = uitab(app.TabGroup);
            app.Tab_5.Title = 'Этап 5';
            app.UITable7 = uitable(app.Tab_5);
            app.UITable7.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable7.RowName = {};
            app.AdddataButton = uibutton(app.Tab_5, 'push');
            app.AdddataButton.ButtonPushedFcn = createCallbackFcn(app, @AdddataButtonPushed, true);
            app.AdddataButton.Text = 'Add data ';
            app.Label_6 = uilabel(app.Tab_5);
            app.Label_6.Text = 'Данные предыдущего шага';
            app.NameEditFieldLabel = uilabel(app.Tab_5);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Tab_5, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @NameEditFieldValueChanged, true);
            app.WriteExcelButton = uibutton(app.Tab_5, 'push');
            app.WriteExcelButton.ButtonPushedFcn = createCallbackFcn(app, @WriteExcelButtonPushed, true);
            app.WriteExcelButton.Text = 'Write Excel';
            app.Tab_6 = uitab(app.TabGroup);
            app.Tab_6.Title = 'Tab';
            app.PointsdisplayButton = uibutton(app.Tab_6, 'push');
            app.PointsdisplayButton.ButtonPushedFcn = createCallbackFcn(app, @PointsdisplayButtonPushed, true);
            app.PointsdisplayButton.Text = 'Points display';
            app.UIAxes = uiaxes(app.Tab_6);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_w0rk_3_3_3_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end